classdef dtft < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DatasignalcontentButtonGroup  matlab.ui.container.ButtonGroup
        FrequencyandexponentialcontentButton  matlab.ui.control.RadioButton
        ExponentialcontentonlyButton  matlab.ui.control.RadioButton
        FrequencycontentonlyButton    matlab.ui.control.RadioButton
        iwnLabel                      matlab.ui.control.Label
        eLabel                        matlab.ui.control.Label
        SimplifyEquationCheckBox      matlab.ui.control.CheckBox
        coswnLabel_6                  matlab.ui.control.Label
        coswnLabel_5                  matlab.ui.control.Label
        coswnLabel_4                  matlab.ui.control.Label
        ProbeVisible                  matlab.ui.control.CheckBox
        DataVisible                   matlab.ui.control.CheckBox
        Image                         matlab.ui.control.Image
        Label_2                       matlab.ui.control.Label
        coswnLabel_3                  matlab.ui.control.Label
        xnLabel_3                     matlab.ui.control.Label
        coswnLabel_2                  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        cosnLabel                     matlab.ui.control.Label
        xnLabel                       matlab.ui.control.Label
        ProbingSignalLabel            matlab.ui.control.Label
        DataSignalLabel               matlab.ui.control.Label
        xnLabel_2                     matlab.ui.control.Label
        AddsineCheckBox               matlab.ui.control.CheckBox
        PhaseoftimesignalradSlider    matlab.ui.control.Slider
        PhaseoftimesignalradSliderLabel  matlab.ui.control.Label
        FrequencyofprobingsignalradnSlider  matlab.ui.control.Slider
        FrequencyofprobingsignalradnSliderLabel  matlab.ui.control.Label
        NumberofsamplesSlider         matlab.ui.control.Slider
        NumberofsamplesSliderLabel    matlab.ui.control.Label
        Summation                     matlab.ui.control.UIAxes
        Product                       matlab.ui.control.UIAxes
        Data                          matlab.ui.control.UIAxes
    end

%% This app was developed by Brian Douglas for the MATLAB Tech Talk video on the z-transform
% It is only intended to create some visuals for the video and is not supposed
% to be a fully functioning app. You can use this as a starting point for
% your own app or you can use it as-is to understand some of the basics of
% the discrete-time Fourier transform.

    methods (Access = private)
        
        function updatePlot(app)
            
            % Grab slider values
            numSamps = round(app.NumberofsamplesSlider.Value, 1);
            freqProb = app.FrequencyofprobingsignalradnSlider.Value;
            phaseSignal = app.PhaseoftimesignalradSlider.Value;
            addSine = app.AddsineCheckBox.Value;
            dataVisible = app.DataVisible.Value;
            probeVisible = app.ProbeVisible.Value;
            simplifyEq = app.SimplifyEquationCheckBox.Value;
            button1 = app.FrequencycontentonlyButton.Value;
            button2 = app.ExponentialcontentonlyButton.Value;
            button3 = app.FrequencyandexponentialcontentButton.Value;

            % Build data signal
            n = 0:numSamps;
            timeSignalFreq = ones(1, length(n));
            timeSignalExpo = ones(1, length(n));
            if button1 || button3
                timeSignalFreq = cos(0.5*n - phaseSignal);
            end

            if button2 || button3
                timeSignalExpo = exp(-0.1*n);
            end

            timeSignal = timeSignalFreq .* timeSignalExpo;

            % Build probing signal
            if addSine
                probeSignal = cos(freqProb*n) + 1i*sin(freqProb*n);
                app.cosnLabel.Text = "cos(" + num2str(freqProb, 2) + "n)";
                app.coswnLabel_4.Text = "+ i sin(" + num2str(freqProb, 2) + "n)";
                app.coswnLabel_4.Visible = 1;
                app.coswnLabel_2.Text = "cos(" + num2str(freqProb, 2) + "n)";
                app.coswnLabel_5.Text = "+ i sin(" + num2str(freqProb, 2) + "n)";
                app.coswnLabel_5.Visible = 1;

                if simplifyEq
                    app.coswnLabel_3.Visible = 0;
                    app.coswnLabel_6.Visible = 0;
                    app.iwnLabel.Visible = 1;
                    app.eLabel.Visible = 1;
                else
                    app.coswnLabel_3.Text = "(cos(wn)";
                    app.coswnLabel_6.Text = "+ i sin(wn))";
                    app.coswnLabel_6.Visible = 1;
                    app.iwnLabel.Visible = 0;
                    app.eLabel.Visible = 0;
                    app.coswnLabel_3.Visible = 1;
                end

            else
                probeSignal = cos(freqProb*n);
                app.cosnLabel.Text = "cos(" + num2str(freqProb, 2) + "n)";
                app.coswnLabel_2.Text = "cos(" + num2str(freqProb, 2) + "n)";
                app.coswnLabel_3.Text = "cos(wn)";
                app.coswnLabel_3.Visible = 1;
                app.iwnLabel.Visible = 0;
                app.eLabel.Visible = 0;
                app.coswnLabel_4.Visible = 0;
                app.coswnLabel_5.Visible = 0;
                app.coswnLabel_6.Visible = 0;
            end

            % Plot data and probing signal
            if isempty(app.Data.Children)               
                stem(n, imag(probeSignal), 'filled', 'Color', [0 0.4 0.6], 'Parent', app.Data);
                stem(n, real(probeSignal), 'filled','Parent', app.Data);
                stem(n, timeSignal, 'k', 'filled', 'Parent', app.Data);
                app.Data.Children(1).MarkerSize = 15;
                app.Data.Children(2).MarkerSize = 10;
                app.Data.Children(3).MarkerSize = 10;
                axis(app.Data, [-1, max(n)+1, -1.2, 1.2]);
            else
                app.Data.Children(1).YData = timeSignal;
                app.Data.Children(1).XData = n;
                app.Data.Children(2).YData = real(probeSignal);
                app.Data.Children(2).XData = n;
                app.Data.Children(3).YData = imag(probeSignal);
                app.Data.Children(3).XData = n;
                axis(app.Data, [-1, max(n)+1, -1.2, 1.2]);
            end  

            % Plot Product signal
            product = timeSignal .* probeSignal;
            if isempty(app.Product.Children)
                stem(n, imag(product), 'filled',  'Parent', app.Product);
                stem(n, real(product), 'filled',  'Parent', app.Product);                
                axis(app.Product, [-1, max(n)+1, -1.2, 1.2]);
                app.Product.Children(1).MarkerSize = 20;
                app.Product.Children(2).MarkerSize = 20;
            else
                app.Product.Children(1).YData = real(product);
                app.Product.Children(1).XData = n;
                app.Product.Children(2).YData = imag(product);
                app.Product.Children(2).XData = n;
                axis(app.Product, [-1, max(n)+1, -1.2, 1.2]);
            end

            % Build Summation signal
            productSum = sum(product);

            % Sweep through all w
            w = 0:0.01:1;
            fullSummation = zeros(numel(w),1);
            for idx = 1:numel(w)
                wsweep = w(idx);
                tempProbe = cos(wsweep*n) + 1i*sin(wsweep*n);
                tempProduct = tempProbe .* timeSignal;
                fullSummation(idx) = sum(tempProduct);
            end

            % Plot Summation signal
            if isempty(app.Summation.Children)                
                plot(w, imag(fullSummation),  'Parent', app.Summation);
                plot(w, real(fullSummation),  'Parent', app.Summation);
                plot(freqProb, imag(productSum), '.b',  'Parent', app.Summation);
                plot(freqProb, real(productSum), '.r',  'Parent', app.Summation);
                app.Summation.Children(1).MarkerSize = 50;
                app.Summation.Children(2).MarkerSize = 50;
                axis(app.Summation, [0, 1, -25, 25]);
            else
                app.Summation.Children(1).YData = real(productSum);
                app.Summation.Children(1).XData = freqProb;
                app.Summation.Children(2).YData = imag(productSum);
                app.Summation.Children(2).XData = freqProb;
                app.Summation.Children(3).YData = real(fullSummation);
                app.Summation.Children(3).XData = w;
                app.Summation.Children(4).YData = imag(fullSummation);
                app.Summation.Children(4).XData = w;
                axis(app.Summation, [0, 1, -25, 25]);
            end

            % Check visiblity of signals
            if dataVisible
                app.Data.Children(1).Visible = 1;
            else
                app.Data.Children(1).Visible = 0;
            end

            if probeVisible
                app.Data.Children(2).Visible = 1;
                app.Product.Children(1).Visible = 1;
                app.Summation.Children(1).Visible = 1;
                app.Summation.Children(3).Visible = 1;
            else
                app.Data.Children(2).Visible = 0;
                app.Product.Children(1).Visible = 0;
                app.Summation.Children(1).Visible = 0;
                app.Summation.Children(3).Visible = 0;
            end

            if addSine && probeVisible
                app.Data.Children(3).Visible = 1;
                app.Product.Children(2).Visible = 1;
                app.Summation.Children(4).Visible = 1;
                app.Summation.Children(2).Visible = 1;
            else
                app.Data.Children(3).Visible = 0;
                app.Product.Children(2).Visible = 0;
                app.Summation.Children(4).Visible = 0;
                app.Summation.Children(2).Visible = 0;
            end        
             
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updatePlot(app)
        end

        % Value changed function: NumberofsamplesSlider
        function NumberofsamplesSliderValueChanged(app, event)
            updatePlot(app);
        end

        % Callback function: FrequencyofprobingsignalradnSlider, 
        % ...and 1 other component
        function FrequencyofprobingsignalradnSliderValueChanged(app, event)
            updatePlot(app);
        end

        % Callback function: PhaseoftimesignalradSlider, 
        % ...and 1 other component
        function PhaseoftimesignalradSliderValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: AddsineCheckBox
        function AddsineCheckBoxValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: ProbeVisible
        function ProbeVisibleValueChanged(app, event)
            updatePlot(app);
            
        end

        % Value changed function: DataVisible
        function DataVisibleValueChanged(app, event)
            updatePlot(app);
            
        end

        % Value changed function: SimplifyEquationCheckBox
        function SimplifyEquationCheckBoxValueChanged(app, event)
            updatePlot(app);
            
        end

        % Callback function
        function addExpoCheckValueChanged(app, event)
            if ~app.addFreqCheck.Value && ~app.addExpoCheck.Value
                app.addFreqCheck.Value = 1;
            end            
            updatePlot(app);
            
        end

        % Callback function
        function addFreqCheckValueChanged(app, event)
            if ~app.addFreqCheck.Value && ~app.addExpoCheck.Value
                app.addExpoCheck.Value = 1;
            end 
            updatePlot(app);
            
        end

        % Selection changed function: DatasignalcontentButtonGroup
        function DatasignalcontentButtonGroupSelectionChanged(app, event)
            updatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Data = uiaxes(app.UIFigure);
            title(app.Data, 'Discrete Time Data')
            xlabel(app.Data, 'Sample number, n')
            app.Product = uiaxes(app.UIFigure);
            title(app.Product, 'Product')
            xlabel(app.Product, 'Sample number, n')
            app.Summation = uiaxes(app.UIFigure);
            title(app.Summation, 'Summation')
            xlabel(app.Summation, 'Frequency, rad/n')
            app.NumberofsamplesSliderLabel = uilabel(app.UIFigure);
            app.NumberofsamplesSliderLabel.Text = 'Number of samples';
            app.NumberofsamplesSlider = uislider(app.UIFigure);
            app.NumberofsamplesSlider.Limits = [20 60];
            app.NumberofsamplesSlider.MajorTicks = [20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.NumberofsamplesSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofsamplesSliderValueChanged, true);
            app.NumberofsamplesSlider.MinorTicks = [];
            app.NumberofsamplesSlider.Value = 20;
            app.FrequencyofprobingsignalradnSliderLabel = uilabel(app.UIFigure);
            app.FrequencyofprobingsignalradnSliderLabel.Text = 'Frequency of probing signal, rad/n';
            app.FrequencyofprobingsignalradnSlider = uislider(app.UIFigure);
            app.FrequencyofprobingsignalradnSlider.Limits = [0 1];
            app.FrequencyofprobingsignalradnSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.FrequencyofprobingsignalradnSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyofprobingsignalradnSliderValueChanged, true);
            app.FrequencyofprobingsignalradnSlider.ValueChangingFcn = createCallbackFcn(app, @FrequencyofprobingsignalradnSliderValueChanged, true);
            app.FrequencyofprobingsignalradnSlider.MinorTicks = [];
            app.FrequencyofprobingsignalradnSlider.Value = 0.2;
            app.PhaseoftimesignalradSliderLabel = uilabel(app.UIFigure);
            app.PhaseoftimesignalradSliderLabel.Text = 'Phase of time signal, rad';
            app.PhaseoftimesignalradSlider = uislider(app.UIFigure);
            app.PhaseoftimesignalradSlider.Limits = [0 6];
            app.PhaseoftimesignalradSlider.MajorTicks = [0 0.6 1.2 1.8 2.4 3 3.6 4.2 4.8 5.4 6];
            app.PhaseoftimesignalradSlider.MajorTickLabels = {'0', '0.6', '1.2', '1.8', '2.4', '3', '3.6', '4.2', '4.8', '5.4', '6'};
            app.PhaseoftimesignalradSlider.ValueChangedFcn = createCallbackFcn(app, @PhaseoftimesignalradSliderValueChanged, true);
            app.PhaseoftimesignalradSlider.ValueChangingFcn = createCallbackFcn(app, @PhaseoftimesignalradSliderValueChanged, true);
            app.PhaseoftimesignalradSlider.MinorTicks = [];
            app.AddsineCheckBox = uicheckbox(app.UIFigure);
            app.AddsineCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddsineCheckBoxValueChanged, true);
            app.AddsineCheckBox.Text = 'Add sine';
            app.xnLabel_2 = uilabel(app.UIFigure);
            app.xnLabel_2.Text = 'x[n]';
            app.DataSignalLabel = uilabel(app.UIFigure);
            app.DataSignalLabel.Text = 'Data Signal:';
            app.ProbingSignalLabel = uilabel(app.UIFigure);
            app.ProbingSignalLabel.Text = 'Probing Signal:';
            app.xnLabel = uilabel(app.UIFigure);
            app.xnLabel.Text = 'x[n]';
            app.cosnLabel = uilabel(app.UIFigure);
            app.cosnLabel.Text = 'cos(0.22n)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '*';
            app.coswnLabel_2 = uilabel(app.UIFigure);
            app.coswnLabel_2.Text = 'cos(wn)';
            app.xnLabel_3 = uilabel(app.UIFigure);
            app.xnLabel_3.Text = 'x[n]';
            app.coswnLabel_3 = uilabel(app.UIFigure);
            app.coswnLabel_3.Text = 'cos(wn)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '*';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'summation.png');
            app.DataVisible = uicheckbox(app.UIFigure);
            app.DataVisible.ValueChangedFcn = createCallbackFcn(app, @DataVisibleValueChanged, true);
            app.DataVisible.Text = 'Visible';
            app.DataVisible.Value = true;
            app.ProbeVisible = uicheckbox(app.UIFigure);
            app.ProbeVisible.ValueChangedFcn = createCallbackFcn(app, @ProbeVisibleValueChanged, true);
            app.ProbeVisible.Text = 'Visible';
            app.ProbeVisible.Value = true;
            app.coswnLabel_4 = uilabel(app.UIFigure);
            app.coswnLabel_4.Visible = 'off';
            app.coswnLabel_4.Text = '+ cos(wn)';
            app.coswnLabel_5 = uilabel(app.UIFigure);
            app.coswnLabel_5.Visible = 'off';
            app.coswnLabel_5.Text = '+ cos(wn)';
            app.coswnLabel_6 = uilabel(app.UIFigure);
            app.coswnLabel_6.Visible = 'off';
            app.coswnLabel_6.Text = '+ cos(wn)';
            app.SimplifyEquationCheckBox = uicheckbox(app.UIFigure);
            app.SimplifyEquationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimplifyEquationCheckBoxValueChanged, true);
            app.SimplifyEquationCheckBox.Text = 'Simplify Equation';
            app.eLabel = uilabel(app.UIFigure);
            app.eLabel.Visible = 'off';
            app.eLabel.Text = 'e';
            app.iwnLabel = uilabel(app.UIFigure);
            app.iwnLabel.Visible = 'off';
            app.iwnLabel.Text = '-iwn';
            app.DatasignalcontentButtonGroup = uibuttongroup(app.UIFigure);
            app.DatasignalcontentButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatasignalcontentButtonGroupSelectionChanged, true);
            app.DatasignalcontentButtonGroup.Title = 'Data signal content';
            app.FrequencycontentonlyButton = uiradiobutton(app.DatasignalcontentButtonGroup);
            app.FrequencycontentonlyButton.Text = 'Frequency content only';
            app.FrequencycontentonlyButton.Value = true;
            app.ExponentialcontentonlyButton = uiradiobutton(app.DatasignalcontentButtonGroup);
            app.ExponentialcontentonlyButton.Text = 'Exponential content only';
            app.FrequencyandexponentialcontentButton = uiradiobutton(app.DatasignalcontentButtonGroup);
            app.FrequencyandexponentialcontentButton.Text = 'Frequency and exponential content';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dtft

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end