classdef FFT_app_for_video < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        dftVis                          matlab.ui.control.CheckBox
        DiscreteFourierTransformLabel   matlab.ui.control.Label
        f1                              matlab.ui.control.Label
        f2                              matlab.ui.control.Label
        f3                              matlab.ui.control.Label
        f4                              matlab.ui.control.Label
        f5                              matlab.ui.control.Label
        f6                              matlab.ui.control.Image
        p7                              matlab.ui.control.Label
        p8                              matlab.ui.control.Label
        d1                              matlab.ui.control.Image
        p9                              matlab.ui.control.Image
        vert1                           matlab.ui.control.Image
        f8                              matlab.ui.control.CheckBox
        f9                              matlab.ui.control.Label
        f10                             matlab.ui.control.Label
        f7                              matlab.ui.control.Label
        dataVis                         matlab.ui.control.CheckBox
        p1                              matlab.ui.control.Label
        p2                              matlab.ui.control.Label
        p3                              matlab.ui.control.Label
        p4                              matlab.ui.control.Label
        p5                              matlab.ui.control.Label
        probVis                         matlab.ui.control.CheckBox
        d2                              matlab.ui.control.Label
        CorrelatingSignalLabel          matlab.ui.control.Label
        TimeSignalLabel                 matlab.ui.control.Label
        CorrelatingsignalnumberkSlider  matlab.ui.control.Slider
        CorrelatingsignalnumberkSliderLabel  matlab.ui.control.Label
        p6                              matlab.ui.control.Label
        f11                             matlab.ui.control.Image
        p10                             matlab.ui.control.Image
        vert2                           matlab.ui.control.Image
        Product                         matlab.ui.control.UIAxes
        Data                            matlab.ui.control.UIAxes
    end

    %% This app was developed for the MATLAB Tech Talk video on the FFT
    % It is only intended to create some visuals for the video and is not 
    % supposed to be a fully functioning app. There are probably lots of 
    % bugs. You can use this as a starting point for your own app or you 
    % can use it as-is to understand some of the basics of the FFT.


    methods (Access = private)

        function updatePlot(app)

            % Build data signal
            fs = 10;            % Sampling frequency
            T = 1/fs;             % Sampling period
            N = 10;
            t = (0:N-1)*T;        % Time vector
            timeSignal = sin(2*pi*t);

            % Build probing signal
            n = 0:N-1;
            k = app.CorrelatingsignalnumberkSlider.Value;
            probeSignal = exp(-1i*2*pi()/N.*n*k);

            % Plot everything

            if isempty(app.Data.Children)
                plot(n, timeSignal, '.', 'Color', 'k', 'Parent', app.Data);
                plot(n, real(probeSignal), '.', 'Color', 'b', 'Parent', app.Data);
                plot(n, imag(probeSignal), '.', 'Color', 'r', 'Parent', app.Data);
                app.Data.Children(1).MarkerSize = 40;
                app.Data.Children(2).MarkerSize = 40;
                app.Data.Children(3).MarkerSize = 60;
                axis(app.Data, [-.1, max(n)+1, -2, 2]);
            else

                app.Data.Children(1).YData = imag(probeSignal);
                app.Data.Children(1).XData = n;
                app.Data.Children(2).YData = real(probeSignal);
                app.Data.Children(2).XData = n;
                app.Data.Children(3).YData = timeSignal;
                app.Data.Children(3).XData = n;
                axis(app.Data, [-.1, max(n)+1, -2, 2]);
            end

            % Set visibility
            if app.probVis.Value == 0
                app.Data.Children(2).Visible = 0;
                app.Data.Children(1).Visible = 0;
                app.p1.Visible = 0;
                app.p2.Visible = 0;
                app.p3.Visible = 0;
                app.p4.Visible = 0;
                app.p5.Visible = 0;
                app.p6.Visible = 0;
                app.p7.Visible = 0;
                app.p8.Visible = 0;
                app.p9.Visible = 0;
                app.p10.Visible = 0;
            else
                app.Data.Children(2).Visible = 1;
                app.Data.Children(1).Visible = 1;
                app.p1.Visible = 1;
                app.p2.Visible = 1;
                app.p3.Visible = 1;
                app.p4.Visible = 1;
                app.p5.Visible = 1;
                app.p6.Visible = 1;
                app.p7.Visible = 1;
                app.p8.Visible = 1;
                app.p9.Visible = 1;
                app.p10.Visible = 1;
            end

            if app.dftVis.Value == 0
                app.f1.Visible = 0;
                app.f2.Visible = 0;
                app.f3.Visible = 0;
                app.f4.Visible = 0;
                app.f5.Visible = 0;
                app.f6.Visible = 0;
                app.f7.Visible = 0;
                app.f8.Visible = 0;
                app.f9.Visible = 0;
                app.f10.Visible = 0;
                app.f11.Visible = 0;
            else
                app.f1.Visible = 1;
                app.f2.Visible = 1;
                app.f3.Visible = 1;
                app.f4.Visible = 1;
                app.f5.Visible = 1;
                app.f6.Visible = 1;
                app.f7.Visible = 1;
                app.f8.Visible = 1;
                app.f9.Visible = 1;
                app.f10.Visible = 1;
                app.f11.Visible = 1;
            end

            % Absolute Value
            if app.f8.Value == 1
                for temp = 1:10
                    yd1 = app.Product.Children(2*temp-1).YData;
                    yd2 = app.Product.Children(2*temp).YData;
                    app.Product.Children(2*temp - 1).YData = max(abs(yd1), abs(yd2));
                    app.Product.Children(2*temp).YData = max(abs(yd1), abs(yd2));
                end

                app.vert1.Visible = 1;
                app.vert2.Visible = 1;
            end

            if app.dataVis.Value == 0
                app.Data.Children(3).Visible = 0;
                app.d1.Visible = 0;
                app.d2.Visible = 0;
            else
                app.Data.Children(3).Visible = 1;
                app.d1.Visible = 1;
                app.d2.Visible = 1;
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Build data signal
            fs = 10;            % Sampling frequency
            T = 1/fs;             % Sampling period
            N = 10;            % Length of signal
            t = (0:N-1)*T;        % Time vector
            timeSignal = sin(2*pi*t);

            % Build probing signal
            n = 0:N-1;
            for k = 0:10
                probeSignal = exp(-1i*2*pi()/N.*n*k);

                product = sum(timeSignal .* probeSignal);

                stem(k, real(product), 'filled', 'Color', 'b', 'Parent', app.Product);
                stem(k, imag(product), 'filled', 'Color', 'r', 'Parent', app.Product);
                axis(app.Product, [-.1, max(n)+1, -6, 6]);
            end


            for temp = 1:22
                app.Product.Children(temp).MarkerSize = 20;
                app.Product.Children(temp).Visible = 0;
            end

            app.vert1.Visible = 0;
            app.vert2.Visible = 0;

            updatePlot(app);
        end

        % Callback function: CorrelatingsignalnumberkSlider, 
        % ...and 1 other component
        function FrequencyofprobingsignalradnSliderValueChanged(app, event)
            sliderValue = app.CorrelatingsignalnumberkSlider.Value;
            app.CorrelatingsignalnumberkSlider.Value = round(sliderValue);

             % Build Product signal
             if app.dftVis.Value == 1
                k = app.CorrelatingsignalnumberkSlider.Value;
                app.Product.Children(22-(2*k)).Visible = 1;
                app.Product.Children(22-(2*k+1)).Visible = 1;
             end
             N = 10;
            axis(app.Product, [-.1, N, -7, 7]);
            updatePlot(app);
        end

        % Value changed function: probVis
        function probVisValueChanged(app, event)
            updatePlot(app);

        end

        % Value changed function: dataVis
        function dataVisValueChanged(app, event)
            updatePlot(app);

        end

        % Value changed function: f8
        function f8ValueChanged(app, event)
            updatePlot(app);
            
        end

        % Value changed function: dftVis
        function dftVisValueChanged(app, event)
            updatePlot(app);
            
        end

        % Callback function: not associated with a component
        function NumberofsamplesNSliderValueChanged(app, event)
            value = app.NumberofsamplesNSlider.Value;
            app.NumberofsamplesNSlider.Value = round(value);
            N = app.NumberofsamplesNSlider.Value;
            axis(app.Product, [-.1, N, -7, 7]);
            app.CorrelatingsignalnumberkSlider.Limits = [0 app.NumberofsamplesNSlider.Value];
            updatePlot(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Data = uiaxes(app.UIFigure);
            title(app.Data, 'Discrete Time Data')
            xlabel(app.Data, 'Sample number, n')
            app.Product = uiaxes(app.UIFigure);
            title(app.Product, 'Discrete Fourier Transform')
            xlabel(app.Product, 'k')
            app.vert2 = uiimage(app.UIFigure);
            app.vert2.ImageSource = fullfile(pathToMLAPP, 'vertline.png');
            app.p10 = uiimage(app.UIFigure);
            app.p10.ImageSource = fullfile(pathToMLAPP, 'black.png');
            app.f11 = uiimage(app.UIFigure);
            app.f11.ImageSource = fullfile(pathToMLAPP, 'summation.png');
            app.p6 = uilabel(app.UIFigure);
            app.p6.Text = 'k';
            app.CorrelatingsignalnumberkSliderLabel = uilabel(app.UIFigure);
            app.CorrelatingsignalnumberkSliderLabel.Text = 'Correlating signal number, k';
            app.CorrelatingsignalnumberkSlider = uislider(app.UIFigure);
            app.CorrelatingsignalnumberkSlider.Limits = [0 9];
            app.CorrelatingsignalnumberkSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.CorrelatingsignalnumberkSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.CorrelatingsignalnumberkSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyofprobingsignalradnSliderValueChanged, true);
            app.CorrelatingsignalnumberkSlider.ValueChangingFcn = createCallbackFcn(app, @FrequencyofprobingsignalradnSliderValueChanged, true);
            app.CorrelatingsignalnumberkSlider.MinorTicks = [];
            app.TimeSignalLabel = uilabel(app.UIFigure);
            app.TimeSignalLabel.Text = 'Time Signal';
            app.CorrelatingSignalLabel = uilabel(app.UIFigure);
            app.CorrelatingSignalLabel.Text = 'Correlating Signal';
            app.d2 = uilabel(app.UIFigure);
            app.d2.Text = 'x[n]';
            app.probVis = uicheckbox(app.UIFigure);
            app.probVis.ValueChangedFcn = createCallbackFcn(app, @probVisValueChanged, true);
            app.probVis.Text = '';
            app.p5 = uilabel(app.UIFigure);
            app.p5.Text = 'n';
            app.p4 = uilabel(app.UIFigure);
            app.p4.Text = 'i2π';
            app.p3 = uilabel(app.UIFigure);
            app.p3.Text = 'N';
            app.p2 = uilabel(app.UIFigure);
            app.p2.Text = 'e';
            app.p1 = uilabel(app.UIFigure);
            app.p1.Text = '-';
            app.dataVis = uicheckbox(app.UIFigure);
            app.dataVis.ValueChangedFcn = createCallbackFcn(app, @dataVisValueChanged, true);
            app.dataVis.Text = '';
            app.dataVis.Value = true;
            app.f7 = uilabel(app.UIFigure);
            app.f7.Text = 'x[n]';
            app.f10 = uilabel(app.UIFigure);
            app.f10.Text = 'e';
            app.f9 = uilabel(app.UIFigure);
            app.f9.Text = '.';
            app.f8 = uicheckbox(app.UIFigure);
            app.f8.ValueChangedFcn = createCallbackFcn(app, @f8ValueChanged, true);
            app.f8.Text = 'Absolute Value';
            app.vert1 = uiimage(app.UIFigure);
            app.vert1.ImageSource = fullfile(pathToMLAPP, 'vertline.png');
            app.p9 = uiimage(app.UIFigure);
            app.p9.ImageSource = fullfile(pathToMLAPP, 'bluered.png');
            app.d1 = uiimage(app.UIFigure);
            app.d1.ImageSource = fullfile(pathToMLAPP, 'black.png');
            app.p8 = uilabel(app.UIFigure);
            app.p8.Text = 'N = number of samples';
            app.p7 = uilabel(app.UIFigure);
            app.p7.Text = 'n = sample number';
            app.f6 = uiimage(app.UIFigure);
            app.f6.ImageSource = fullfile(pathToMLAPP, 'black.png');
            app.f5 = uilabel(app.UIFigure);
            app.f5.Text = 'n';
            app.f4 = uilabel(app.UIFigure);
            app.f4.Text = 'i2π';
            app.f3 = uilabel(app.UIFigure);
            app.f3.Text = 'N';
            app.f2 = uilabel(app.UIFigure);
            app.f2.Text = '-';
            app.f1 = uilabel(app.UIFigure);
            app.f1.Text = 'k';
            app.DiscreteFourierTransformLabel = uilabel(app.UIFigure);
            app.DiscreteFourierTransformLabel.Text = 'Discrete Fourier Transform';
            app.dftVis = uicheckbox(app.UIFigure);
            app.dftVis.ValueChangedFcn = createCallbackFcn(app, @dftVisValueChanged, true);
            app.dftVis.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFT_app_for_video

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end